classdef eq_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        RawSignalButton         matlab.ui.control.Button
        FilterSignalButton      matlab.ui.control.Button
        FilterWhiteNoiseButton  matlab.ui.control.Button
        SignalEditField         matlab.ui.control.EditField
        SignalEditFieldLabel    matlab.ui.control.Label
        LoadButton              matlab.ui.control.Button
        Panel                   matlab.ui.container.Panel
        dispHP                  matlab.ui.control.NumericEditField
        dispBP5                 matlab.ui.control.NumericEditField
        dispBP4                 matlab.ui.control.NumericEditField
        dispBP3                 matlab.ui.control.NumericEditField
        dispBP2                 matlab.ui.control.NumericEditField
        dispBP1                 matlab.ui.control.NumericEditField
        dispLP                  matlab.ui.control.NumericEditField
        BWKnob_5                matlab.ui.control.Knob
        BWKnob_5Label           matlab.ui.control.Label
        BWKnob_4                matlab.ui.control.Knob
        BWKnob_4Label           matlab.ui.control.Label
        BWKnob_3                matlab.ui.control.Knob
        BWKnob_3Label           matlab.ui.control.Label
        BWKnob_2                matlab.ui.control.Knob
        BWKnob_2Label           matlab.ui.control.Label
        BWKnob                  matlab.ui.control.Knob
        BWKnobLabel             matlab.ui.control.Label
        FcKnob_7                matlab.ui.control.Knob
        FcKnob_6                matlab.ui.control.Knob
        FcKnob_5                matlab.ui.control.Knob
        FcKnob_4                matlab.ui.control.Knob
        FcKnob_3                matlab.ui.control.Knob
        FcKnob_2                matlab.ui.control.Knob
        FcKnob                  matlab.ui.control.Knob
        HighPassSlider          matlab.ui.control.Slider
        HighPassSliderLabel     matlab.ui.control.Label
        BandPassSlider_5        matlab.ui.control.Slider
        BandPassSlider_5Label   matlab.ui.control.Label
        BandPassSlider_4        matlab.ui.control.Slider
        BandPassSlider_4Label   matlab.ui.control.Label
        BandPassSlider_3        matlab.ui.control.Slider
        BandPassSlider_3Label   matlab.ui.control.Label
        BandPassSlider_2        matlab.ui.control.Slider
        BandPassSlider_2Label   matlab.ui.control.Label
        BandPassSlider          matlab.ui.control.Slider
        BandPassSliderLabel     matlab.ui.control.Label
        LowPassSlider           matlab.ui.control.Slider
        LowPassSliderLabel      matlab.ui.control.Label
        UIAxes_2                matlab.ui.control.UIAxes
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        fs = 48000;

        lp_fc =0; bp1_fc =0; bp2_fc =0; bp3_fc =0; bp4_fc =0; bp5_fc =0; hp_fc =0;
        lp_Gdb =0; bp1_Gdb =0; bp2_Gdb =0; bp3_Gdb =0; bp4_Gdb =0; bp5_Gdb =0; hp_Gdb =0;
        bp1_BW =0; bp2_BW =0; bp3_BW =0; bp4_BW =0; bp5_BW =0;
        sig =0; sig_fs =0;
    
        gains = zeros(1,7);
        coef_mat = zeros(14,3);
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: FcKnob_7
        function FcKnob_7ValueChanging(app, event)
            app.lp_fc = event.Value;
            app.dispLP.Value = app.lp_fc;
            [app.coef_mat(1,:), app.coef_mat(2,:)] = calc_lp_coeffs(app.lp_fc,app.lp_Gdb,app.fs);
            plot_eq_freq_resp(app.UIAxes,app.UIAxes_2,app.coef_mat,app.fs,app.gains);
        end

        % Value changing function: FcKnob_6
        function FcKnob_6ValueChanging(app, event)
            app.hp_fc  = event.Value;
            app.dispHP.Value = app.hp_fc;
            [app.coef_mat(13,:), app.coef_mat(14,:)] = calc_hp_coeffs(app.hp_fc,app.hp_Gdb,app.fs);
            plot_eq_freq_resp(app.UIAxes,app.UIAxes_2,app.coef_mat,app.fs,app.gains);
        end

        % Value changing function: FcKnob
        function FcKnobValueChanging(app, event)
            app.bp1_fc = event.Value;
            app.dispBP1.Value = app.bp1_fc;
            [app.coef_mat(3,:), app.coef_mat(4,:)] = calc_bp_coeffs(app.bp1_fc,app.bp1_Gdb,app.bp1_BW,app.fs);
            plot_eq_freq_resp(app.UIAxes,app.UIAxes_2,app.coef_mat,app.fs,app.gains);
        end

        % Value changing function: FcKnob_2
        function FcKnob_2ValueChanging(app, event)
            app.bp2_fc = event.Value;
            app.dispBP2.Value = app.bp2_fc;
            [app.coef_mat(5,:), app.coef_mat(6,:)] = calc_bp_coeffs(app.bp2_fc,app.bp2_Gdb,app.bp2_BW,app.fs);
            plot_eq_freq_resp(app.UIAxes,app.UIAxes_2,app.coef_mat,app.fs,app.gains);
        end

        % Value changing function: FcKnob_3
        function FcKnob_3ValueChanging(app, event)
            app.bp3_fc = event.Value;
            app.dispBP3.Value = app.bp3_fc;
            [app.coef_mat(7,:), app.coef_mat(8,:)] = calc_bp_coeffs(app.bp3_fc,app.bp3_Gdb,app.bp3_BW,app.fs);
            plot_eq_freq_resp(app.UIAxes,app.UIAxes_2,app.coef_mat,app.fs,app.gains);
        end

        % Value changing function: FcKnob_4
        function FcKnob_4ValueChanging(app, event)
            app.bp4_fc = event.Value;
            app.dispBP4.Value = app.bp4_fc;
            [app.coef_mat(9,:), app.coef_mat(10,:)] = calc_bp_coeffs(app.bp4_fc,app.bp4_Gdb,app.bp4_BW,app.fs);
            plot_eq_freq_resp(app.UIAxes,app.UIAxes_2,app.coef_mat,app.fs,app.gains);
        end

        % Value changing function: FcKnob_5
        function FcKnob_5ValueChanging(app, event)
            app.bp5_fc = event.Value;
            app.dispBP5.Value = app.bp5_fc;
            [app.coef_mat(11,:), app.coef_mat(12,:)] = calc_bp_coeffs(app.bp5_fc,app.bp5_Gdb,app.bp5_BW,app.fs);
            plot_eq_freq_resp(app.UIAxes,app.UIAxes_2,app.coef_mat,app.fs,app.gains);
        end

        % Value changing function: LowPassSlider
        function LowPassSliderValueChanging(app, event)
            app.lp_Gdb = event.Value;
            app.gains(1) = event.Value;
            [app.coef_mat(1,:), app.coef_mat(2,:)] = calc_lp_coeffs(app.lp_fc,app.lp_Gdb,app.fs);
            plot_eq_freq_resp(app.UIAxes,app.UIAxes_2,app.coef_mat,app.fs,app.gains);
        end

        % Value changing function: BandPassSlider
        function BandPassSliderValueChanging(app, event)
            app.bp1_Gdb = event.Value;
            app.gains(2) = event.Value;
            [app.coef_mat(3,:), app.coef_mat(4,:)] = calc_bp_coeffs(app.bp1_fc,app.bp1_Gdb,app.bp1_BW,app.fs);
            plot_eq_freq_resp(app.UIAxes,app.UIAxes_2,app.coef_mat,app.fs,app.gains);
        end

        % Value changing function: BandPassSlider_2
        function BandPassSlider_2ValueChanging(app, event)
            app.bp2_Gdb = event.Value;
            app.gains(3) = event.Value;
            [app.coef_mat(5,:), app.coef_mat(6,:)] = calc_bp_coeffs(app.bp2_fc,app.bp2_Gdb,app.bp2_BW,app.fs);
            plot_eq_freq_resp(app.UIAxes,app.UIAxes_2,app.coef_mat,app.fs,app.gains);
        end

        % Value changing function: BandPassSlider_3
        function BandPassSlider_3ValueChanging(app, event)
            app.bp3_Gdb = event.Value;
            app.gains(4) = event.Value;
            [app.coef_mat(7,:), app.coef_mat(8,:)] = calc_bp_coeffs(app.bp3_fc,app.bp3_Gdb,app.bp3_BW,app.fs);
            plot_eq_freq_resp(app.UIAxes,app.UIAxes_2,app.coef_mat,app.fs,app.gains);
        end

        % Value changing function: BandPassSlider_4
        function BandPassSlider_4ValueChanging(app, event)
            app.bp4_Gdb = event.Value;
            app.gains(5) = event.Value;
            [app.coef_mat(9,:), app.coef_mat(10,:)] = calc_bp_coeffs(app.bp4_fc,app.bp4_Gdb,app.bp4_BW,app.fs);
            plot_eq_freq_resp(app.UIAxes,app.UIAxes_2,app.coef_mat,app.fs,app.gains);
        end

        % Value changing function: BandPassSlider_5
        function BandPassSlider_5ValueChanging(app, event)
            app.bp5_Gdb = event.Value;
            app.gains(6) = event.Value;
            [app.coef_mat(11,:), app.coef_mat(12,:)] = calc_bp_coeffs(app.bp5_fc,app.bp5_Gdb,app.bp5_BW,app.fs);
            plot_eq_freq_resp(app.UIAxes,app.UIAxes_2,app.coef_mat,app.fs,app.gains);
        end

        % Value changing function: HighPassSlider
        function HighPassSliderValueChanging(app, event)
            app.hp_Gdb = event.Value;
            app.gains(7) = event.Value;
            [app.coef_mat(13,:), app.coef_mat(14,:)] = calc_hp_coeffs(app.hp_fc,app.hp_Gdb,app.fs);
            plot_eq_freq_resp(app.UIAxes,app.UIAxes_2,app.coef_mat,app.fs,app.gains);
        end

        % Value changing function: BWKnob
        function BWKnobValueChanging(app, event)
            app.bp1_BW = event.Value;
            [app.coef_mat(3,:), app.coef_mat(4,:)] = calc_bp_coeffs(app.bp1_fc,app.bp1_Gdb,app.bp1_BW,app.fs);
            plot_eq_freq_resp(app.UIAxes,app.UIAxes_2,app.coef_mat,app.fs,app.gains);
        end

        % Value changing function: BWKnob_2
        function BWKnob_2ValueChanging(app, event)
            app.bp2_BW = event.Value;
            [app.coef_mat(5,:), app.coef_mat(6,:)] = calc_bp_coeffs(app.bp2_fc,app.bp2_Gdb,app.bp2_BW,app.fs);
            plot_eq_freq_resp(app.UIAxes,app.UIAxes_2,app.coef_mat,app.fs,app.gains);
        end

        % Value changing function: BWKnob_3
        function BWKnob_3ValueChanging(app, event)
            app.bp3_BW = event.Value;
            [app.coef_mat(7,:), app.coef_mat(8,:)] = calc_bp_coeffs(app.bp3_fc,app.bp3_Gdb,app.bp3_BW,app.fs);
            plot_eq_freq_resp(app.UIAxes,app.UIAxes_2,app.coef_mat,app.fs,app.gains);
        end

        % Value changing function: BWKnob_4
        function BWKnob_4ValueChanging(app, event)
            app.bp4_BW = event.Value;
            [app.coef_mat(9,:), app.coef_mat(10,:)] = calc_bp_coeffs(app.bp4_fc,app.bp4_Gdb,app.bp4_BW,app.fs);
            plot_eq_freq_resp(app.UIAxes,app.UIAxes_2,app.coef_mat,app.fs,app.gains);
        end

        % Value changing function: BWKnob_5
        function BWKnob_5ValueChanging(app, event)
            app.bp5_BW = event.Value;
            [app.coef_mat(11,:), app.coef_mat(12,:)] = calc_bp_coeffs(app.bp5_fc,app.bp5_Gdb,app.bp5_BW,app.fs);
            plot_eq_freq_resp(app.UIAxes,app.UIAxes_2,app.coef_mat,app.fs,app.gains);
        end

        % Button pushed function: FilterWhiteNoiseButton
        function FilterWhiteNoiseButtonPushed(app, event)
            filter_white_noise(app.gains,app.coef_mat,app.fs);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [fName fPath] = uigetfile(".wav",".mp3");
            full_path = [fPath fName];
            [app.sig,app.sig_fs] = audioread(full_path);
            app.sig = app.sig(:,1)';
            app.SignalEditField.Value = fName;
        end

        % Button pushed function: FilterSignalButton
        function FilterSignalButtonPushed(app, event)
            filter_sig(app.sig,app.gains,app.coef_mat,app.sig_fs,app.fs);   
            
        end

        % Button pushed function: RawSignalButton
        function RawSignalButtonPushed(app, event)
            plt_spectrogram(app.sig,app.fs);
            sound(app.sig,app.sig_fs);

        end

        % Value changed function: FcKnob_7
        function FcKnob_7ValueChanged(app, event)
            value = app.FcKnob_7.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Frequency Response')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 24000];
            app.UIAxes.YLim = [-50 50];
            app.UIAxes_2 = uiaxes(app.Panel);
            title(app.UIAxes_2, 'Phase Response')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_2.XLim = [0 24000];
            app.UIAxes_2.YLim = [-50 50];
            app.LowPassSliderLabel = uilabel(app.Panel);
            app.LowPassSliderLabel.Text = 'Low Pass';
            app.LowPassSlider = uislider(app.Panel);
            app.LowPassSlider.Limits = [-30 30];
            app.LowPassSlider.ValueChangingFcn = createCallbackFcn(app, @LowPassSliderValueChanging, true);
            app.BandPassSliderLabel = uilabel(app.Panel);
            app.BandPassSliderLabel.Text = 'Band Pass';
            app.BandPassSlider = uislider(app.Panel);
            app.BandPassSlider.Limits = [-30 30];
            app.BandPassSlider.ValueChangingFcn = createCallbackFcn(app, @BandPassSliderValueChanging, true);
            app.BandPassSlider_2Label = uilabel(app.Panel);
            app.BandPassSlider_2Label.Text = 'Band Pass';
            app.BandPassSlider_2 = uislider(app.Panel);
            app.BandPassSlider_2.Limits = [-30 30];
            app.BandPassSlider_2.ValueChangingFcn = createCallbackFcn(app, @BandPassSlider_2ValueChanging, true);
            app.BandPassSlider_3Label = uilabel(app.Panel);
            app.BandPassSlider_3Label.Text = 'Band Pass';
            app.BandPassSlider_3 = uislider(app.Panel);
            app.BandPassSlider_3.Limits = [-30 30];
            app.BandPassSlider_3.ValueChangingFcn = createCallbackFcn(app, @BandPassSlider_3ValueChanging, true);
            app.BandPassSlider_4Label = uilabel(app.Panel);
            app.BandPassSlider_4Label.Text = 'Band Pass';
            app.BandPassSlider_4 = uislider(app.Panel);
            app.BandPassSlider_4.Limits = [-30 30];
            app.BandPassSlider_4.ValueChangingFcn = createCallbackFcn(app, @BandPassSlider_4ValueChanging, true);
            app.BandPassSlider_5Label = uilabel(app.Panel);
            app.BandPassSlider_5Label.Text = 'Band Pass';
            app.BandPassSlider_5 = uislider(app.Panel);
            app.BandPassSlider_5.Limits = [-30 30];
            app.BandPassSlider_5.ValueChangingFcn = createCallbackFcn(app, @BandPassSlider_5ValueChanging, true);
            app.HighPassSliderLabel = uilabel(app.Panel);
            app.HighPassSliderLabel.Text = 'High Pass';
            app.HighPassSlider = uislider(app.Panel);
            app.HighPassSlider.Limits = [-30 30];
            app.HighPassSlider.ValueChangingFcn = createCallbackFcn(app, @HighPassSliderValueChanging, true);
            app.FcKnob = uiknob(app.Panel, 'continuous');
            app.FcKnob.Limits = [20 24000];
            app.FcKnob.MajorTickLabels = {'0', '6', '12', '18', '24'};
            app.FcKnob.ValueChangingFcn = createCallbackFcn(app, @FcKnobValueChanging, true);
            app.FcKnob.Value = 1000;
            app.FcKnob_2 = uiknob(app.Panel, 'continuous');
            app.FcKnob_2.Limits = [20 24000];
            app.FcKnob_2.MajorTickLabels = {'0', '6', '12', '18', '24'};
            app.FcKnob_2.ValueChangingFcn = createCallbackFcn(app, @FcKnob_2ValueChanging, true);
            app.FcKnob_2.Value = 2500;
            app.FcKnob_3 = uiknob(app.Panel, 'continuous');
            app.FcKnob_3.Limits = [20 24000];
            app.FcKnob_3.MajorTickLabels = {'0', '6', '12', '18', '24'};
            app.FcKnob_3.ValueChangingFcn = createCallbackFcn(app, @FcKnob_3ValueChanging, true);
            app.FcKnob_3.Value = 5000;
            app.FcKnob_4 = uiknob(app.Panel, 'continuous');
            app.FcKnob_4.Limits = [20 24000];
            app.FcKnob_4.MajorTickLabels = {'0', '6', '12', '18', '24'};
            app.FcKnob_4.ValueChangingFcn = createCallbackFcn(app, @FcKnob_4ValueChanging, true);
            app.FcKnob_4.Value = 10000;
            app.FcKnob_5 = uiknob(app.Panel, 'continuous');
            app.FcKnob_5.Limits = [20 24000];
            app.FcKnob_5.MajorTickLabels = {'0', '6', '12', '18', '24'};
            app.FcKnob_5.ValueChangingFcn = createCallbackFcn(app, @FcKnob_5ValueChanging, true);
            app.FcKnob_5.Value = 15000;
            app.FcKnob_6 = uiknob(app.Panel, 'continuous');
            app.FcKnob_6.Limits = [0 24000];
            app.FcKnob_6.MajorTickLabels = {'0', '6', '12', '18', '24'};
            app.FcKnob_6.ValueChangingFcn = createCallbackFcn(app, @FcKnob_6ValueChanging, true);
            app.FcKnob_6.Value = 23500;
            app.FcKnob_7 = uiknob(app.Panel, 'continuous');
            app.FcKnob_7.Limits = [0 24000];
            app.FcKnob_7.MajorTickLabels = {'0', '6', '12', '18', '24'};
            app.FcKnob_7.ValueChangedFcn = createCallbackFcn(app, @FcKnob_7ValueChanged, true);
            app.FcKnob_7.ValueChangingFcn = createCallbackFcn(app, @FcKnob_7ValueChanging, true);
            app.FcKnob_7.Value = 100;
            app.BWKnobLabel = uilabel(app.Panel);
            app.BWKnobLabel.Text = 'BW';
            app.BWKnob = uiknob(app.Panel, 'continuous');
            app.BWKnob.Limits = [1 2500];
            app.BWKnob.ValueChangingFcn = createCallbackFcn(app, @BWKnobValueChanging, true);
            app.BWKnob.Value = 200;
            app.BWKnob_2Label = uilabel(app.Panel);
            app.BWKnob_2Label.Text = 'BW';
            app.BWKnob_2 = uiknob(app.Panel, 'continuous');
            app.BWKnob_2.Limits = [1 2500];
            app.BWKnob_2.ValueChangingFcn = createCallbackFcn(app, @BWKnob_2ValueChanging, true);
            app.BWKnob_2.Value = 200;
            app.BWKnob_3Label = uilabel(app.Panel);
            app.BWKnob_3Label.Text = 'BW';
            app.BWKnob_3 = uiknob(app.Panel, 'continuous');
            app.BWKnob_3.Limits = [1 2500];
            app.BWKnob_3.ValueChangingFcn = createCallbackFcn(app, @BWKnob_3ValueChanging, true);
            app.BWKnob_3.Value = 200;
            app.BWKnob_4Label = uilabel(app.Panel);
            app.BWKnob_4Label.Text = 'BW';
            app.BWKnob_4 = uiknob(app.Panel, 'continuous');
            app.BWKnob_4.Limits = [1 2500];
            app.BWKnob_4.ValueChangingFcn = createCallbackFcn(app, @BWKnob_4ValueChanging, true);
            app.BWKnob_4.Value = 200;
            app.BWKnob_5Label = uilabel(app.Panel);
            app.BWKnob_5Label.Text = 'BW';
            app.BWKnob_5 = uiknob(app.Panel, 'continuous');
            app.BWKnob_5.Limits = [1 2500];
            app.BWKnob_5.ValueChangingFcn = createCallbackFcn(app, @BWKnob_5ValueChanging, true);
            app.BWKnob_5.Value = 200;
            app.dispLP = uieditfield(app.Panel, 'numeric');
            app.dispBP1 = uieditfield(app.Panel, 'numeric');
            app.dispBP2 = uieditfield(app.Panel, 'numeric');
            app.dispBP3 = uieditfield(app.Panel, 'numeric');
            app.dispBP4 = uieditfield(app.Panel, 'numeric');
            app.dispBP5 = uieditfield(app.Panel, 'numeric');
            app.dispHP = uieditfield(app.Panel, 'numeric');
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SignalEditFieldLabel = uilabel(app.UIFigure);
            app.SignalEditFieldLabel.Text = 'Signal:';
            app.SignalEditField = uieditfield(app.UIFigure, 'text');
            app.FilterWhiteNoiseButton = uibutton(app.UIFigure, 'push');
            app.FilterWhiteNoiseButton.ButtonPushedFcn = createCallbackFcn(app, @FilterWhiteNoiseButtonPushed, true);
            app.FilterWhiteNoiseButton.Text = 'Filter White Noise';
            app.FilterSignalButton = uibutton(app.UIFigure, 'push');
            app.FilterSignalButton.ButtonPushedFcn = createCallbackFcn(app, @FilterSignalButtonPushed, true);
            app.FilterSignalButton.Text = 'Filter Signal';
            app.RawSignalButton = uibutton(app.UIFigure, 'push');
            app.RawSignalButton.ButtonPushedFcn = createCallbackFcn(app, @RawSignalButtonPushed, true);
            app.RawSignalButton.Text = 'Raw Signal';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = eq_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end