classdef RoomSimApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ProcessedButton          matlab.ui.control.Button
        CleanButton              matlab.ui.control.Button
        AudioFileEditField       matlab.ui.control.EditField
        AudioFileEditFieldLabel  matlab.ui.control.Label
        BrowseButton             matlab.ui.control.Button
        DecayKnob                matlab.ui.control.Knob
        DecayKnobLabel           matlab.ui.control.Label
        BlendKnob                matlab.ui.control.Knob
        BlendKnobLabel           matlab.ui.control.Label
        ProcSpect                matlab.ui.control.UIAxes
        OrigSpect                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        b = 0.275; % gain factor
        a = 0.25; % delay factor
        x; % signal to apply on
        N = 0;
        out; % processed signal
        fs;
        init_flag = 0;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changing function: BlendKnob
        function BlendKnobValueChanging(app, event)
            app.b = event.Value/100 + 0.275;
            app.out = schroeder_reverberator(app.out,app.x,app.b,app.a,app.N,app.fs);
            app.out = RelativeRMS(0,app.x,app.out);
            if(app.init_flag ~= 0)
                show_spectrograms(app.OrigSpect,app.ProcSpect,app.x,app.out,app.fs);
            end
        end

        % Value changing function: DecayKnob
        function DecayKnobValueChanging(app, event)
            app.a = event.Value/100 + 0.25;
            app.out = schroeder_reverberator(app.out,app.x,app.b,app.a,app.N,app.fs);
            app.out = RelativeRMS(0,app.x,app.out);
            if(app.init_flag ~= 0)
                show_spectrograms(app.OrigSpect,app.ProcSpect,app.x,app.out,app.fs);
            end
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [fname, fpath] = uigetfile(".wav",".mp3");
            file_path = [fpath fname];
            [app.x, app.fs] = audioread(file_path);
            app.x = app.x(:,1);
            app.N = length(app.x);
            app.AudioFileEditField.Value = fname;
            app.init_flag = 1;
        end

        % Button pushed function: CleanButton
        function CleanButtonPushed(app, event)
            if(app.init_flag ~= 0)
                sound(app.x,app.fs);
            end
        end

        % Button pushed function: ProcessedButton
        function ProcessedButtonPushed(app, event)
            if(app.init_flag ~= 0)
                sound(app.out,app.fs);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OrigSpect = uiaxes(app.UIFigure);
            title(app.OrigSpect, 'Original Signal Spectrogram')
            xlabel(app.OrigSpect, 'X')
            ylabel(app.OrigSpect, 'Y')
            zlabel(app.OrigSpect, 'Z')
            app.ProcSpect = uiaxes(app.UIFigure);
            title(app.ProcSpect, 'Processed Signal Spectrogram')
            xlabel(app.ProcSpect, 'X')
            ylabel(app.ProcSpect, 'Y')
            zlabel(app.ProcSpect, 'Z')
            app.BlendKnobLabel = uilabel(app.UIFigure);
            app.BlendKnobLabel.Text = 'Blend';
            app.BlendKnob = uiknob(app.UIFigure, 'continuous');
            app.BlendKnob.ValueChangingFcn = createCallbackFcn(app, @BlendKnobValueChanging, true);
            app.DecayKnobLabel = uilabel(app.UIFigure);
            app.DecayKnobLabel.Text = 'Decay';
            app.DecayKnob = uiknob(app.UIFigure, 'continuous');
            app.DecayKnob.ValueChangingFcn = createCallbackFcn(app, @DecayKnobValueChanging, true);
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.AudioFileEditFieldLabel = uilabel(app.UIFigure);
            app.AudioFileEditFieldLabel.Text = 'Audio File';
            app.AudioFileEditField = uieditfield(app.UIFigure, 'text');
            app.CleanButton = uibutton(app.UIFigure, 'push');
            app.CleanButton.ButtonPushedFcn = createCallbackFcn(app, @CleanButtonPushed, true);
            app.CleanButton.Text = 'Clean ';
            app.ProcessedButton = uibutton(app.UIFigure, 'push');
            app.ProcessedButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessedButtonPushed, true);
            app.ProcessedButton.Text = 'Processed';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RoomSimApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end